classdef mission < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ParametersTab                   matlab.ui.container.Tab
        DurationhEditField              matlab.ui.control.EditField
        DurationhEditFieldLabel         matlab.ui.control.Label
        SampletimesEditField            matlab.ui.control.EditField
        SampletimesEditFieldLabel       matlab.ui.control.Label
        SimulationParametersLabel       matlab.ui.control.Label
        HourEditField                   matlab.ui.control.EditField
        HourEditFieldLabel              matlab.ui.control.Label
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        RequiredEbNoRx                  matlab.ui.control.EditField
        RequiredEbNoMbpsEditFieldLabel  matlab.ui.control.Label
        GaintoNoiseTemperatureratioRx   matlab.ui.control.EditField
        GaintoNoiseTemperatureratiodBKEditFieldLabel  matlab.ui.control.Label
        PreReceiverLossRx               matlab.ui.control.EditField
        PreReceiverLossdBEditFieldLabel  matlab.ui.control.Label
        SystemLossRx                    matlab.ui.control.EditField
        SystemLossdBEditFieldLabel      matlab.ui.control.Label
        SatelliteReceiverParametersLabel  matlab.ui.control.Label
        showSatPattern                  matlab.ui.control.CheckBox
        satAntenna                      matlab.ui.control.DropDown
        AntennaDropDownLabel            matlab.ui.control.Label
        SatelliteAntennaLabel           matlab.ui.control.Label
        satMountingAnglesTxRx           matlab.ui.control.EditField
        MountingAnglesmLabel            matlab.ui.control.Label
        satPowerTx                      matlab.ui.control.EditField
        PowerdBWLabel                   matlab.ui.control.Label
        satBitRateTx                    matlab.ui.control.EditField
        BitRateMbpsLabel                matlab.ui.control.Label
        FrequencyHzLabel                matlab.ui.control.Label
        satFrequencyTx                  matlab.ui.control.EditField
        satMountingLocationTxRx         matlab.ui.control.EditField
        MountingLocationmLabel          matlab.ui.control.Label
        satNameTxRx                     matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        SatelliteTransmitterParametersLabel  matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CalculateDownLinkButton         matlab.ui.control.RadioButton
        CalculateUpLinkButton           matlab.ui.control.RadioButton
        RunSimulationButton             matlab.ui.control.Button
        info_gs                         matlab.ui.control.EditField
        SelectxlsxfileButton            matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        SelecttlefileButton             matlab.ui.control.Button
        GroundstationdataselectionLabel  matlab.ui.control.Label
        OrbitaldataselectionLabel       matlab.ui.control.Label
        MissionParametersLabel          matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        ExporttocsvfileButton           matlab.ui.control.Button
        ExporttoxlsxfileButton          matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        InformationTab                  matlab.ui.container.Tab
        SoftwareVersionv10Label         matlab.ui.control.Label
        DegreeAerospaceEngineeringinNavigationLabel  matlab.ui.control.Label
        AuthorCsarBoraoMoratinosLabel   matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        MISSIONPLANNERPROGRAMLabel      matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        tleFile  % Description
        gs % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)

            % Mission data
            date = app.DateDatePicker.Value;
            hour = datetime(app.HourEditField.Value, 'InputFormat', 'HH:mm:ss');
              
            startTime =  date + hours(hour.Hour) + minutes(hour.Minute) + seconds(hour.Second);
            duration = datetime(app.DurationhEditField.Value, 'InputFormat', 'HH'); %str2double(app.DurationhEditField);
            sampleTime = str2double(app.SampletimesEditField.Value);
            stopTime = startTime + hours(duration.Hour);
        
            sc = satelliteScenario(startTime,stopTime,sampleTime);  

            %----------------------------------------------------------
            % Orbital objects info
            try
                sat = satellite(sc,app.EditField.Value);
                if size(sat,2) ~= 1
                    disp(strcat('Error: File "',app.EditField.Value ,'" includes a satellite constellation. Program works with an unique satellite')); 
                    return 
                end
            catch
                disp(strcat('Error: File "',app.EditField.Value ,'" not working. Enter a valid .tle file')); 
            end
            cam = conicalSensor(sat,"Name","Satellite","MaxViewAngle",70);
    
            % Satellite equipment assignament
            if app.CalculateDownLinkButton.Value == true

                % Satellite transmitter
                satTx = transmitter(sat, ...
                "Name", app.satNameTxRx.Value, ...
                "MountingLocation", str2num(app.satMountingLocationTxRx.Value), ... 
                "MountingAngles", str2num(app.satMountingAnglesTxRx.Value), ... 
                "Frequency",str2double(app.satFrequencyTx.Value), ...    
                "BitRate",str2double(app.satBitRateTx.Value), ...
                "Power",str2double(app.satPowerTx.Value));  

                if app.satAntenna.Value == "Gaussian Antenna"
                    gaussianAntenna(satTx, ...
                            "DishDiameter",0.3); % meters
                else
                    satTx.Antenna = app.satAntenna.Value;
                end

            elseif app.CalculateUpLinkButton.Value == true
    
                % Satellite receiver
                satRx = receiver(sat, ...
                "Name", app.satNameTxRx.Value, ...
                "MountingLocation", str2num(app.satMountingLocationTxRx.Value), ... 
                "MountingAngles", str2num(app.satMountingAnglesTxRx.Value), ...
                "RequiredEbNo", str2double(app.RequiredEbNoRx.Value), ...
                "SystemLoss", str2double(app.SystemLossRx.Value), ...
                "PreReceiverLoss", str2double(app.PreReceiverLossRx.Value), ...
                "GainToNoiseTemperatureRatio", str2double(app.GaintoNoiseTemperatureratioRx.Value), ...
                "RequiredEbNo", str2double(app.RequiredEbNoRx.Value));    

                if app.satAntenna.Value == "Gaussian Antenna"
                    gaussianAntenna(satRx, ...
                            "DishDiameter",0.3); % meters
                else
                    satRx.Antenna = app.satAntenna.Value;
                end
            end
            
            % Table initialization
            app.UITable.ColumnName = ["Source","Target","IntervalNumber","StartTime","EndTime","Duration","StartOrbit","EndOrbit"];
            app.UITable.Visible = true;
            app.UITable.Data = [];

            %---------------------------------------------------------
            % Ground objects info

            try
                [num,~,raw] = xlsread(app.info_gs.Value);
            catch
                disp(strcat('Error: File "',app.info_gs.Value,'" doesn`t exists. Enter a valid .xlsx file')); 
            end

            % Creating Ground stations object
            for i = 2:size(num,2)+1

                % Ground station creation
                gsName = raw{1,i};
                gsLat = raw{2,i};
                gsLon = raw{3,i};
                gsMinElevationAngle = raw{4,i};
                gs(i-1) = groundStation(sc,"Name",gsName,"Latitude",gsLat,"Longitude",gsLon,"MinElevationAngle",gsMinElevationAngle);
    
                % Gimbal configuration
                gimbalMountingLocation = raw{5,i};
                gimbalMountingAngles = raw{6,i};
                if isnan(gimbalMountingLocation)
                    current = gs(i-1);
                else
                    gsGimbal = gimbal(gs(i-1),"MountingLocation",str2num(gimbalMountingLocation),"MountingAngles",str2num(gimbalMountingAngles));
                    % Set the gimbals to track the satellite.
                    current = gsGimbal;
                    pointAt(current,sat);
                end
            
                % Receivers configuration
                if app.CalculateDownLinkButton.Value == true

%                     gsMountingLocationRx = raw{7,i};
%                     if isnan(gsMountingLocationRx)
%                         gsMountingLocationRx = [0;0;0];
%                     end
                    gsGainToNoiseTemperatureRatioRx = raw{8,i};
                    if isnan(gsGainToNoiseTemperatureRatioRx)
                        gsGainToNoiseTemperatureRatioRx = 3;
                    end
                    gsRequiredEbNoRx = raw{9,i};
                    if isnan(gsRequiredEbNoRx)
                        gsRequiredEbNoRx = 10;
                    end
                    gsReceiverSystemLossRx = raw{10,i};
                    if isnan(gsReceiverSystemLossRx)
                        gsReceiverSystemLossRx = 5;
                    end
                    gsPreReceiverLossRx = raw{11,i};
                    if isnan(gsPreReceiverLossRx)
                        gsPreReceiverLossRx = 3;
                    end
                    gsAntennaRx = raw{12,i};

                    % Create receiver
                    gsRx(i-1) = receiver(current, ...
                                    "Name", strcat(gs(i-1).Name, ' Rx'), ...
                                    "GainToNoiseTemperatureRatio",gsGainToNoiseTemperatureRatioRx, ... 
                                    "RequiredEbNo",gsRequiredEbNoRx, ...
                                    "SystemLoss",gsReceiverSystemLossRx,...
                                    "PreReceiverLoss",gsPreReceiverLossRx); 
                    if strcmp(gsAntennaRx,'Gaussian')
                         gaussianAntenna(gsRx(i-1), "DishDiameter",5);
                    end
                    
                    % Create link
%                     pointAt(current,sat);
                    lnk = link(satTx,gsRx(i-1));
                    
                    % show intervals (result)
                    intervals = linkIntervals(lnk);
                    currentData = app.UITable.Data;
                    newData = vertcat(currentData, intervals);
                    app.UITable.Data = newData;
                    app.ExporttoxlsxfileButton.Visible = true;
                    app.ExporttocsvfileButton.Visible = true;
                
                % Transmitters configuration
                elseif app.CalculateUpLinkButton.Value == true

                    gsSystemLossTx = raw{13,i};
                    if isnan(gsSystemLossTx)
                        gsSystemLossTx = 5;
                    end
                    gsFrequencyTx = raw{14,i};
                    if isnan(gsFrequencyTx)
                        gsFrequencyTx = 14e9;
                    end
                    gsBitRateTx = raw{15,i};
                    if isnan(gsBitRateTx)
                        gsBitRateTx = 10;
                    end
                    gsPowerTx = raw{16,i};
                    if isnan(gsPowerTx)
                        gsPowerTx = 12;
                    end
                    gsAntennaTx = raw{12,i};

                    % Creating transmitter
                    gsTx(i-1) = transmitter(current, ...
                                    "Name", strcat(gs(i-1).Name, ' Tx'), ...
                                    "SystemLoss",gsSystemLossTx, ... 
                                    "Frequency",gsFrequencyTx, ...
                                    "BitRate",gsBitRateTx,...
                                    "Power",gsPowerTx);                          
%                                   "MountingLocation",str2num(gsMountingLocationRx), ...

                    if strcmp(gsAntennaTx,'Gaussian')
                         gaussianAntenna(gsTx(i-1), "DishDiameter",5);
                    end
                    
                    % Create link
%                     pointAt(current,sat);
                    lnk = link(gsTx(i-1),satRx);

                    % show intervals (result)
                    intervals = linkIntervals(lnk);
                    currentData = app.UITable.Data;
                    newData = vertcat(currentData, intervals);
                    app.UITable.Data = newData;
                    app.ExporttoxlsxfileButton.Visible = true;
                    app.ExporttocsvfileButton.Visible = true;
                end
            end
            
            %------------------------------------
            if app.showSatPattern.Value == true
               if app.CalculateUpLinkButton.Value == true
%                    pattern(satRx,1e9);
                   for i = 1:size(gsTx,2)
                        pattern(gsTx(i));
                   end
                elseif app.CalculateDownLinkButton.Value == true
                   pattern(satTx);
%                    for i = 1:size(gsRx,2)
%                        pattern(gsRx(i),app.satFrequencyTx.Value);
%                    end
                end
            end
            
            fieldOfView(cam);
            groundTrack(sat,LeadTime=3600);
            play(sc);

        end

        % Button pushed function: SelecttlefileButton
        function SelecttlefileButtonPushed(app, event)
            [fileName,filePath] = uigetfile();
            if fileName ~= 0
                fullFileName = fullfile(filePath,fileName);
                [~,fileNameNoExt,fileExt] = fileparts(fullFileName);
                if strcmp(fileExt,'.tle')
                    tleFile = [fileNameNoExt fileExt];
                    app.EditField.Value = tleFile;
                    assignin('base','selectedFile',fullFileName);
                else
                    app.EditField.Value = 'Error: wrong file extension';
                end
            end
        end

        % Button pushed function: SelectxlsxfileButton
        function SelectxlsxfileButtonPushed(app, event)
            [fileName,filePath] = uigetfile();
            if fileName ~= 0
                fullFileName = fullfile(filePath,fileName);
                [~,fileNameNoExt,fileExt] = fileparts(fullFileName);
                if strcmp(fileExt,'.xlsx')
                    fileDisplayString= [fileNameNoExt fileExt];
                    app.info_gs.Value = fileDisplayString 
                    assignin('base','selectedFile',fullFileName);
                else
                    app.EditField.Value = 'Error: wrong file extension';
                end
            end            
        end

        % Button down function: ButtonGroup
        function ButtonGroupButtonDown(app, event)
            if app.CalculateDownLinkButton.Value == true
                app.NameEditFieldLabel.Visible = true;
                app.satNameTxRx.Visible = true;
            end  
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if app.CalculateDownLinkButton.Value == true
                app.SatelliteTransmitterParametersLabel.Visible = true;
                app.SatelliteReceiverParametersLabel.Visible = false;

                app.satFrequencyTx.Visible = true;
                app.FrequencyHzLabel.Visible = true;
                app.satBitRateTx.Visible = true;
                app.BitRateMbpsLabel.Visible = true;
                app.satPowerTx.Visible = true;
                app.PowerdBWLabel.Visible = true;
               
                app.SystemLossRx.Visible = false;
                app.SystemLossdBEditFieldLabel.Visible = false;
                app.PreReceiverLossRx.Visible = false;
                app.PreReceiverLossdBEditFieldLabel.Visible = false;
                app.GaintoNoiseTemperatureratioRx.Visible = false;
                app.GaintoNoiseTemperatureratiodBKEditFieldLabel.Visible = false;
                app.RequiredEbNoRx.Visible = false;
                app.RequiredEbNoMbpsEditFieldLabel.Visible = false;

            end 
            if app.CalculateUpLinkButton.Value == true
                app.SatelliteTransmitterParametersLabel.Visible = false;
                app.SatelliteReceiverParametersLabel.Visible = true;

                app.satFrequencyTx.Visible = false;
                app.FrequencyHzLabel.Visible = false;
                app.satBitRateTx.Visible = false;
                app.BitRateMbpsLabel.Visible = false;
                app.satPowerTx.Visible = false;
                app.PowerdBWLabel.Visible = false;

                app.SystemLossRx.Visible = true;
                app.SystemLossdBEditFieldLabel.Visible = true;
                app.PreReceiverLossRx.Visible = true;
                app.PreReceiverLossdBEditFieldLabel.Visible = true;
                app.GaintoNoiseTemperatureratioRx.Visible = true;
                app.GaintoNoiseTemperatureratiodBKEditFieldLabel.Visible = true;
                app.RequiredEbNoRx.Visible = true; 
                app.RequiredEbNoMbpsEditFieldLabel.Visible = true;
                
            end   
        end

        % Button pushed function: ExporttoxlsxfileButton
        function ExporttoxlsxfileButtonPushed(app, event)
            [fileName, pathName] = uiputfile('*.xlsx','Save as');
            if fileName == 0
                return;
            end
            fullPath = fullfile(pathName, fileName);
            writetable(app.UITable.Data, fullPath, "AutoFitWidth",false);
        end

        % Button pushed function: ExporttocsvfileButton
        function ExporttocsvfileButtonPushed(app, event)
            [file, path] = uiputfile('*.csv', 'Save as');
            if file == 0
                return
            end
            
            filename = fullfile(path, file);
            writetable(app.UITable.Data, filename);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MISSIONPLANNERPROGRAMLabel = uilabel(app.UIFigure);
            app.MISSIONPLANNERPROGRAMLabel.Text = 'MISSION PLANNER PROGRAM';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.MissionParametersLabel = uilabel(app.ParametersTab);
            app.MissionParametersLabel.Text = 'Mission Parameters';
            app.OrbitaldataselectionLabel = uilabel(app.ParametersTab);
            app.OrbitaldataselectionLabel.Text = 'Orbital data selection';
            app.GroundstationdataselectionLabel = uilabel(app.ParametersTab);
            app.GroundstationdataselectionLabel.Text = 'Ground station data selection';
            app.SelecttlefileButton = uibutton(app.ParametersTab, 'push');
            app.SelecttlefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttlefileButtonPushed, true);
            app.SelecttlefileButton.Text = 'Select .tle file';
            app.EditField = uieditfield(app.ParametersTab, 'text');
            app.EditField.Enable = 'off';
            app.SelectxlsxfileButton = uibutton(app.ParametersTab, 'push');
            app.SelectxlsxfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectxlsxfileButtonPushed, true);
            app.SelectxlsxfileButton.Text = 'Select .xlsx file';
            app.info_gs = uieditfield(app.ParametersTab, 'text');
            app.info_gs.Enable = 'off';
            app.RunSimulationButton = uibutton(app.ParametersTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ButtonGroup = uibuttongroup(app.ParametersTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ButtonGroupButtonDown, true);
            app.CalculateUpLinkButton = uiradiobutton(app.ButtonGroup);
            app.CalculateUpLinkButton.Text = 'Calculate UpLink';
            app.CalculateUpLinkButton.Value = true;
            app.CalculateDownLinkButton = uiradiobutton(app.ButtonGroup);
            app.CalculateDownLinkButton.Text = 'Calculate DownLink';
            app.SatelliteTransmitterParametersLabel = uilabel(app.ParametersTab);
            app.SatelliteTransmitterParametersLabel.Visible = 'off';
            app.SatelliteTransmitterParametersLabel.Text = 'Satellite Transmitter Parameters';
            app.NameEditFieldLabel = uilabel(app.ParametersTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.satNameTxRx = uieditfield(app.ParametersTab, 'text');
            app.satNameTxRx.Value = 'sat';
            app.MountingLocationmLabel = uilabel(app.ParametersTab);
            app.MountingLocationmLabel.Text = 'Mounting Location (m)';
            app.satMountingLocationTxRx = uieditfield(app.ParametersTab, 'text');
            app.satMountingLocationTxRx.Value = '[0;0;0]';
            app.satFrequencyTx = uieditfield(app.ParametersTab, 'text');
            app.satFrequencyTx.Visible = 'off';
            app.satFrequencyTx.Value = '1e9';
            app.FrequencyHzLabel = uilabel(app.ParametersTab);
            app.FrequencyHzLabel.Visible = 'off';
            app.FrequencyHzLabel.Text = 'Frequency (Hz)';
            app.BitRateMbpsLabel = uilabel(app.ParametersTab);
            app.BitRateMbpsLabel.Visible = 'off';
            app.BitRateMbpsLabel.Text = 'Bit Rate (Mbps)';
            app.satBitRateTx = uieditfield(app.ParametersTab, 'text');
            app.satBitRateTx.Visible = 'off';
            app.satBitRateTx.Value = '10';
            app.PowerdBWLabel = uilabel(app.ParametersTab);
            app.PowerdBWLabel.Visible = 'off';
            app.PowerdBWLabel.Text = 'Power (dBW)';
            app.satPowerTx = uieditfield(app.ParametersTab, 'text');
            app.satPowerTx.Visible = 'off';
            app.satPowerTx.Value = '12';
            app.MountingAnglesmLabel = uilabel(app.ParametersTab);
            app.MountingAnglesmLabel.Text = 'Mounting Angles (m)';
            app.satMountingAnglesTxRx = uieditfield(app.ParametersTab, 'text');
            app.satMountingAnglesTxRx.Value = '[0;0;0]';
            app.SatelliteAntennaLabel = uilabel(app.ParametersTab);
            app.SatelliteAntennaLabel.Text = 'Satellite Antenna';
            app.AntennaDropDownLabel = uilabel(app.ParametersTab);
            app.AntennaDropDownLabel.Text = 'Antenna';
            app.satAntenna = uidropdown(app.ParametersTab);
            app.satAntenna.Items = {'Gaussian Antenna'};
            app.satAntenna.Value = 'Gaussian Antenna';
            app.showSatPattern = uicheckbox(app.ParametersTab);
            app.showSatPattern.Text = 'Show satellite Antenna Pattern';
            app.SatelliteReceiverParametersLabel = uilabel(app.ParametersTab);
            app.SatelliteReceiverParametersLabel.Text = 'Satellite Receiver Parameters';
            app.SystemLossdBEditFieldLabel = uilabel(app.ParametersTab);
            app.SystemLossdBEditFieldLabel.Text = 'System Loss (dB)';
            app.SystemLossRx = uieditfield(app.ParametersTab, 'text');
            app.SystemLossRx.Value = '5';
            app.PreReceiverLossdBEditFieldLabel = uilabel(app.ParametersTab);
            app.PreReceiverLossdBEditFieldLabel.Text = 'PreReceiver Loss (dB)';
            app.PreReceiverLossRx = uieditfield(app.ParametersTab, 'text');
            app.PreReceiverLossRx.Value = '3';
            app.GaintoNoiseTemperatureratiodBKEditFieldLabel = uilabel(app.ParametersTab);
            app.GaintoNoiseTemperatureratiodBKEditFieldLabel.Text = 'Gain to Noise Temperature ratio (dB/K)';
            app.GaintoNoiseTemperatureratioRx = uieditfield(app.ParametersTab, 'text');
            app.GaintoNoiseTemperatureratioRx.Value = '3';
            app.RequiredEbNoMbpsEditFieldLabel = uilabel(app.ParametersTab);
            app.RequiredEbNoMbpsEditFieldLabel.Text = 'Required Eb/No (Mbps)';
            app.RequiredEbNoRx = uieditfield(app.ParametersTab, 'text');
            app.RequiredEbNoRx.Value = '10';
            app.DateDatePickerLabel = uilabel(app.ParametersTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.ParametersTab);
            app.DateDatePicker.Value = datetime([2024 3 6]);
            app.HourEditFieldLabel = uilabel(app.ParametersTab);
            app.HourEditFieldLabel.Text = 'Hour';
            app.HourEditField = uieditfield(app.ParametersTab, 'text');
            app.HourEditField.Value = '09:00:00';
            app.SimulationParametersLabel = uilabel(app.ParametersTab);
            app.SimulationParametersLabel.Text = 'Simulation Parameters';
            app.SampletimesEditFieldLabel = uilabel(app.ParametersTab);
            app.SampletimesEditFieldLabel.Text = 'Sample time (s)';
            app.SampletimesEditField = uieditfield(app.ParametersTab, 'text');
            app.SampletimesEditField.Value = '30';
            app.DurationhEditFieldLabel = uilabel(app.ParametersTab);
            app.DurationhEditFieldLabel.Text = 'Duration (h)';
            app.DurationhEditField = uieditfield(app.ParametersTab, 'text');
            app.DurationhEditField.Value = '20';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UITable = uitable(app.ResultsTab);
            app.UITable.ColumnName = {'asd'; 'asd'; 'asd'; 'asd'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.ExporttoxlsxfileButton = uibutton(app.ResultsTab, 'push');
            app.ExporttoxlsxfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoxlsxfileButtonPushed, true);
            app.ExporttoxlsxfileButton.Visible = 'off';
            app.ExporttoxlsxfileButton.Text = 'Export to .xlsx file';
            app.ExporttocsvfileButton = uibutton(app.ResultsTab, 'push');
            app.ExporttocsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttocsvfileButtonPushed, true);
            app.ExporttocsvfileButton.Visible = 'off';
            app.ExporttocsvfileButton.Text = 'Export to .csv file';
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.AuthorCsarBoraoMoratinosLabel = uilabel(app.InformationTab);
            app.AuthorCsarBoraoMoratinosLabel.Text = 'Author: César Borao Moratinos';
            app.DegreeAerospaceEngineeringinNavigationLabel = uilabel(app.InformationTab);
            app.DegreeAerospaceEngineeringinNavigationLabel.Text = 'Degree: Aerospace Engineering in Navigation';
            app.SoftwareVersionv10Label = uilabel(app.InformationTab);
            app.SoftwareVersionv10Label.Text = 'Software Version: v1.0';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mission

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end